classdef Simulacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulacionGet                 matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TaylorTab                     matlab.ui.container.Tab
        IngreseelgradodelpolinomioLabel  matlab.ui.control.Label
        IngreseelvalorcercanoLabel    matlab.ui.control.Label
        IngreselafuncionLabel         matlab.ui.control.Label
        IngreseelvaloraevaluarLabel   matlab.ui.control.Label
        ValorTaylor                   matlab.ui.control.NumericEditField
        GradoTaylor                   matlab.ui.control.NumericEditField
        CercanoTaylor                 matlab.ui.control.NumericEditField
        FuncionTaylor                 matlab.ui.control.EditField
        IngreselosvaloresrequeridosTaylor  matlab.ui.control.Label
        ObtenerpolinomioButtonTaylor  matlab.ui.control.Button
        DiferenciasTab                matlab.ui.container.Tab
        GenerarvaloresButtonDiferencias  matlab.ui.control.Button
        NDiferencias                  matlab.ui.control.NumericEditField
        IngreseNEditFieldLabel        matlab.ui.control.Label
        yfLabel                       matlab.ui.control.Label
        YfDiferencias                 matlab.ui.control.NumericEditField
        EqualDif02                    matlab.ui.control.Label
        XfDiferencias                 matlab.ui.control.NumericEditField
        Y0Diferencias                 matlab.ui.control.NumericEditField
        EqualDif01                    matlab.ui.control.Label
        X0Diferencias                 matlab.ui.control.NumericEditField
        yLabel                        matlab.ui.control.Label
        IngreselosvaloresrequeridosDiferencias  matlab.ui.control.Label
        FuncionDiferencias            matlab.ui.control.EditField
        IngreselafuncionLabel_2       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function ObtenerpolinomioButtonPushed(app, event)
            functionTaylorText = app.FuncionTaylor.Value;
            gradoTaylorText = app.GradoTaylor.Value;
            cercanoTaylorText = app.CercanoTaylor.Value;
            valorTaylorText = app.ValorTaylor.Value;
            try
                generateTaylor(functionTaylorText, 'x', gradoTaylorText, cercanoTaylorText, valorTaylorText);
            catch ME
                errordlg(sprintf('An error occurred: %s', ME.message), 'Error');
            end
        end

        % Button pushed function: GenerarvaloresButtonDiferencias
        function GenerarvaloresButtonDiferenciasPushed(app, event)
            functionDiferenciasText = app.FuncionDiferencias.Value;
            x0DiferenciasText = app.X0Diferencias.Value;
            y0DiferenciasText = app.Y0Diferencias.Value;
            xfDiferenciasText = app.XfDiferencias.Value;
            yfDiferenciasText = app.YfDiferencias.Value;
            nDiferenciasText = app.NDiferencias.Value;
            try
                generateFinite(functionDiferenciasText, x0DiferenciasText, y0DiferenciasText, xfDiferenciasText, yfDiferenciasText, nDiferenciasText);
            catch ME
                errordlg(sprintf('An error occurred: %s', ME.message), 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulacionGet = uifigure('Visible', 'off');
            app.SimulacionGet.Name = 'Taylor';
            app.TabGroup = uitabgroup(app.SimulacionGet);
            app.TaylorTab = uitab(app.TabGroup);
            app.TaylorTab.Title = 'Taylor';
            app.ObtenerpolinomioButtonTaylor = uibutton(app.TaylorTab, 'push');
            app.ObtenerpolinomioButtonTaylor.Text = 'Obtener polinomio';
            app.IngreselosvaloresrequeridosTaylor = uilabel(app.TaylorTab);
            app.IngreselosvaloresrequeridosTaylor.Text = 'Ingrese los valores requeridos';
            app.FuncionTaylor = uieditfield(app.TaylorTab, 'text');
            app.CercanoTaylor = uieditfield(app.TaylorTab, 'numeric');
            app.GradoTaylor = uieditfield(app.TaylorTab, 'numeric');
            app.ValorTaylor = uieditfield(app.TaylorTab, 'numeric');
            app.IngreseelvaloraevaluarLabel = uilabel(app.TaylorTab);
            app.IngreseelvaloraevaluarLabel.Text = 'Ingrese el valor a evaluar';
            app.IngreselafuncionLabel = uilabel(app.TaylorTab);
            app.IngreselafuncionLabel.Text = 'Ingrese la funcion';
            app.IngreseelvalorcercanoLabel = uilabel(app.TaylorTab);
            app.IngreseelvalorcercanoLabel.Text = 'Ingrese el valor cercano';
            app.IngreseelgradodelpolinomioLabel = uilabel(app.TaylorTab);
            app.IngreseelgradodelpolinomioLabel.Text = 'Ingrese el grado del polinomio';
            app.DiferenciasTab = uitab(app.TabGroup);
            app.DiferenciasTab.Title = 'Diferencias';
            app.IngreselafuncionLabel_2 = uilabel(app.DiferenciasTab);
            app.IngreselafuncionLabel_2.Text = 'Ingrese la funcion';
            app.FuncionDiferencias = uieditfield(app.DiferenciasTab, 'text');
            app.IngreselosvaloresrequeridosDiferencias = uilabel(app.DiferenciasTab);
            app.IngreselosvaloresrequeridosDiferencias.Text = 'Ingrese los valores requeridos en esta sintaxis, (maximo orden 2): y'''' = (p(x))y'' + (g(x))y + (r(x))';
            app.yLabel = uilabel(app.DiferenciasTab);
            app.yLabel.Text = 'y0';
            app.X0Diferencias = uieditfield(app.DiferenciasTab, 'numeric');
            app.EqualDif01 = uilabel(app.DiferenciasTab);
            app.EqualDif01.Text = '=';
            app.Y0Diferencias = uieditfield(app.DiferenciasTab, 'numeric');
            app.XfDiferencias = uieditfield(app.DiferenciasTab, 'numeric');
            app.EqualDif02 = uilabel(app.DiferenciasTab);
            app.EqualDif02.Text = '=';
            app.YfDiferencias = uieditfield(app.DiferenciasTab, 'numeric');
            app.yfLabel = uilabel(app.DiferenciasTab);
            app.yfLabel.Text = 'yf';
            app.IngreseNEditFieldLabel = uilabel(app.DiferenciasTab);
            app.IngreseNEditFieldLabel.Text = 'Ingrese N';
            app.NDiferencias = uieditfield(app.DiferenciasTab, 'numeric');
            app.GenerarvaloresButtonDiferencias = uibutton(app.DiferenciasTab, 'push');
            app.GenerarvaloresButtonDiferencias.ButtonPushedFcn = createCallbackFcn(app, @GenerarvaloresButtonDiferenciasPushed, true);
            app.GenerarvaloresButtonDiferencias.Text = 'Generar valores';
            % Show the figure after all components are created
            app.SimulacionGet.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulacionGet)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulacionGet)
        end
    end
end